classdef Bluetooth_Controlled_LED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Microcontroller_LED_Control_via_BluetoothUIFigure  matlab.ui.Figure
        LEDCONTROLVIAPCBLUETOOTHLabel  matlab.ui.control.Label
        IUTEEE16SECTIONC1MICROCONTROLLER_PROJECTLabel  matlab.ui.control.Label
        LED_1SwitchLabel               matlab.ui.control.Label
        LED_1Switch                    matlab.ui.control.ToggleSwitch
        L1LampLabel                    matlab.ui.control.Label
        L1Lamp                         matlab.ui.control.Lamp
        LED_2SwitchLabel               matlab.ui.control.Label
        LED_2Switch                    matlab.ui.control.ToggleSwitch
        L2LampLabel                    matlab.ui.control.Label
        L2Lamp                         matlab.ui.control.Lamp
        LED_3SwitchLabel               matlab.ui.control.Label
        LED_3Switch                    matlab.ui.control.ToggleSwitch
        L3LampLabel                    matlab.ui.control.Label
        L3Lamp                         matlab.ui.control.Lamp
        LED_4SwitchLabel               matlab.ui.control.Label
        LED_4Switch                    matlab.ui.control.ToggleSwitch
        L4LampLabel                    matlab.ui.control.Label
        L4Lamp                         matlab.ui.control.Lamp
        LED_5SwitchLabel               matlab.ui.control.Label
        LED_5Switch                    matlab.ui.control.ToggleSwitch
        L5LampLabel                    matlab.ui.control.Label
        L5Lamp                         matlab.ui.control.Lamp
        LED_6SwitchLabel               matlab.ui.control.Label
        LED_6Switch                    matlab.ui.control.ToggleSwitch
        L6LampLabel                    matlab.ui.control.Label
        L6Lamp                         matlab.ui.control.Lamp
        LED_7SwitchLabel               matlab.ui.control.Label
        LED_7Switch                    matlab.ui.control.ToggleSwitch
        L7LampLabel                    matlab.ui.control.Label
        L7Lamp                         matlab.ui.control.Lamp
        LED_8SwitchLabel               matlab.ui.control.Label
        LED_8Switch                    matlab.ui.control.ToggleSwitch
        L8LampLabel                    matlab.ui.control.Label
        L8Lamp                         matlab.ui.control.Lamp
        DOT_MATRIXSwitchLabel          matlab.ui.control.Label
        DOT_MATRIXSwitch               matlab.ui.control.ToggleSwitch
        DLampLabel                     matlab.ui.control.Label
        DLamp                          matlab.ui.control.Lamp
        SYSTEM_MAINSKnobLabel          matlab.ui.control.Label
        SYSTEM_MAINSKnob               matlab.ui.control.DiscreteKnob
        LED_STATUSGaugeLabel           matlab.ui.control.Label
        LED_STATUSGauge                matlab.ui.control.LinearGauge
    end

    methods (Access = private)

        % Value changed function: SYSTEM_MAINSKnob
        function SYSTEM_MAINSKnobValueChanged(app, event)
            global bt  on_flag

            value = app.SYSTEM_MAINSKnob.Value;
            if isequal(value,'On')
                if isempty(on_flag)
                    instrhwinfo('Bluetooth')
                    ans.RemoteNames
                    instrhwinfo('Bluetooth','HC-05')
                    bt = Bluetooth('HC-05', 1)
                    fopen(bt)                  % Connects PC bluetooth with HC-05 bluetooth
                    on_flag = 1;
                end
            elseif isequal(value,'Reset')
                fwrite(bt,0);
                app.LED_STATUSGauge.Value = 0;
                app.LED_1Switch.Value = 'Off';
                app.LED_2Switch.Value = 'Off';
                app.LED_3Switch.Value = 'Off';
                app.LED_4Switch.Value = 'Off';
                app.LED_5Switch.Value = 'Off';
                app.LED_6Switch.Value = 'Off';
                app.LED_7Switch.Value = 'Off';
                app.LED_8Switch.Value = 'Off';
                app.DOT_MATRIXSwitch.Value = 'Off';
                app.L1Lamp.Color = 'k';
                app.L2Lamp.Color = 'k';
                app.L3Lamp.Color = 'k';
                app.L4Lamp.Color = 'k';
                app.L5Lamp.Color = 'k';
                app.L6Lamp.Color = 'k';
                app.L7Lamp.Color = 'k';
                app.L8Lamp.Color = 'k';
                app.DLamp.Color = 'k';
            elseif  isequal(value,'Off')
                fwrite(bt,0);
                app.LED_STATUSGauge.Value = 0;
                app.LED_1Switch.Value = 'Off';
                app.LED_2Switch.Value = 'Off';
                app.LED_3Switch.Value = 'Off';
                app.LED_4Switch.Value = 'Off';
                app.LED_5Switch.Value = 'Off';
                app.LED_6Switch.Value = 'Off';
                app.LED_7Switch.Value = 'Off';
                app.LED_8Switch.Value = 'Off';
                app.DOT_MATRIXSwitch.Value = 'Off';
                app.L1Lamp.Color = 'k';
                app.L2Lamp.Color = 'k';
                app.L3Lamp.Color = 'k';
                app.L4Lamp.Color = 'k';
                app.L5Lamp.Color = 'k';
                app.L6Lamp.Color = 'k';
                app.L7Lamp.Color = 'k';
                app.L8Lamp.Color = 'k';
                app.DLamp.Color = 'k';
                fclose(bt);                  % Disconnects bluetooth
                evalin('base','clear all');
            end
        end

        % Value changed function: LED_1Switch
        function LED_1SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_1Switch.Value;
                if isequal(value,'On')
                    app.L1Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,1)                % Value sending to HC-05 from PC bletooth
                else
                    app.L1Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,1)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: LED_2Switch
        function LED_2SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_2Switch.Value;
                if isequal(value,'On')
                    app.L2Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,2)                % Value sending to HC-05 from PC bletooth
                else
                    app.L2Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,2)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: LED_3Switch
        function LED_3SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_3Switch.Value;
                if isequal(value,'On')
                    app.L3Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,3)                % Value sending to HC-05 from PC bletooth
                else
                    app.L3Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,3)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: LED_4Switch
        function LED_4SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_4Switch.Value;
                if isequal(value,'On')
                    app.L4Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,4)                % Value sending to HC-05 from PC bletooth
                else
                    app.L4Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,4)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: LED_5Switch
        function LED_5SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_5Switch.Value;
                if isequal(value,'On')
                    app.L5Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,5)                % Value sending to HC-05 from PC bletooth
                else
                    app.L5Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,5)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: LED_6Switch
        function LED_6SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_6Switch.Value;
                if isequal(value,'On')
                    app.L6Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,6)                % Value sending to HC-05 from PC bletooth
                else
                    app.L6Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,6)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: LED_7Switch
        function LED_7SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_7Switch.Value;
                if isequal(value,'On')
                    app.L7Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,7)                % Value sending to HC-05 from PC bletooth
                else
                    app.L7Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,7)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: LED_8Switch
        function LED_8SwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.LED_8Switch.Value;
                if isequal(value,'On')
                    app.L8Lamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,8)                % Value sending to HC-05 from PC bletooth
                else
                    app.L8Lamp.Color = 'k';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                    fwrite(bt,8)                % Value sending to HC-05 from PC bletooth
                end
            end            
        end

        % Value changed function: DOT_MATRIXSwitch
        function DOT_MATRIXSwitchValueChanged(app, event)
            global bt
            if isequal(app.SYSTEM_MAINSKnob.Value,'On')
                value = app.DOT_MATRIXSwitch.Value;
                if isequal(value,'On')
                    app.DLamp.Color = 'g';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value + 1;
                    fwrite(bt,9)                % Value sending to HC-05 from PC bletooth
                    pause(20)
                    app.DLamp.Color = 'k';
                    app.DOT_MATRIXSwitch.Value = 'Off';
                    app.LED_STATUSGauge.Value = app.LED_STATUSGauge.Value - 1;
                end
            end                        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Microcontroller_LED_Control_via_BluetoothUIFigure = uifigure;
            app.Microcontroller_LED_Control_via_BluetoothUIFigure.Name = 'Microcontroller_LED_Control_via_Bluetooth';
            app.LEDCONTROLVIAPCBLUETOOTHLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LEDCONTROLVIAPCBLUETOOTHLabel.Text = 'LED CONTROL VIA PC BLUETOOTH';
            app.IUTEEE16SECTIONC1MICROCONTROLLER_PROJECTLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.IUTEEE16SECTIONC1MICROCONTROLLER_PROJECTLabel.Text = 'IUT EEE''16     SECTION-C1   MICROCONTROLLER_PROJECT';
            app.LED_1SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_1SwitchLabel.Text = 'LED_1';
            app.LED_1Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_1Switch.ValueChangedFcn = createCallbackFcn(app, @LED_1SwitchValueChanged, true);
            app.L1LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L1LampLabel.Text = 'L1';
            app.L1Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_2SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_2SwitchLabel.Text = 'LED_2';
            app.LED_2Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_2Switch.ValueChangedFcn = createCallbackFcn(app, @LED_2SwitchValueChanged, true);
            app.L2LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L2LampLabel.Text = 'L2';
            app.L2Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_3SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_3SwitchLabel.Text = 'LED_3';
            app.LED_3Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_3Switch.ValueChangedFcn = createCallbackFcn(app, @LED_3SwitchValueChanged, true);
            app.L3LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L3LampLabel.Text = 'L3';
            app.L3Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_4SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_4SwitchLabel.Text = 'LED_4';
            app.LED_4Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_4Switch.ValueChangedFcn = createCallbackFcn(app, @LED_4SwitchValueChanged, true);
            app.L4LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L4LampLabel.Text = 'L4';
            app.L4Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_5SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_5SwitchLabel.Text = 'LED_5';
            app.LED_5Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_5Switch.ValueChangedFcn = createCallbackFcn(app, @LED_5SwitchValueChanged, true);
            app.L5LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L5LampLabel.Text = 'L5';
            app.L5Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_6SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_6SwitchLabel.Text = 'LED_6';
            app.LED_6Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_6Switch.ValueChangedFcn = createCallbackFcn(app, @LED_6SwitchValueChanged, true);
            app.L6LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L6LampLabel.Text = 'L6';
            app.L6Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_7SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_7SwitchLabel.Text = 'LED_7';
            app.LED_7Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_7Switch.ValueChangedFcn = createCallbackFcn(app, @LED_7SwitchValueChanged, true);
            app.L7LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L7LampLabel.Text = 'L7';
            app.L7Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_8SwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_8SwitchLabel.Text = 'LED_8';
            app.LED_8Switch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.LED_8Switch.ValueChangedFcn = createCallbackFcn(app, @LED_8SwitchValueChanged, true);
            app.L8LampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.L8LampLabel.Text = 'L8';
            app.L8Lamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.DOT_MATRIXSwitchLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.DOT_MATRIXSwitchLabel.Text = 'DOT_MATRIX';
            app.DOT_MATRIXSwitch = uiswitch(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'toggle');
            app.DOT_MATRIXSwitch.ValueChangedFcn = createCallbackFcn(app, @DOT_MATRIXSwitchValueChanged, true);
            app.DLampLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.DLampLabel.Text = 'D';
            app.DLamp = uilamp(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.SYSTEM_MAINSKnobLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.SYSTEM_MAINSKnobLabel.Text = 'SYSTEM_MAINS';
            app.SYSTEM_MAINSKnob = uiknob(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'discrete');
            app.SYSTEM_MAINSKnob.Items = {'Off', 'On', 'Reset'};
            app.SYSTEM_MAINSKnob.ValueChangedFcn = createCallbackFcn(app, @SYSTEM_MAINSKnobValueChanged, true);
            app.LED_STATUSGaugeLabel = uilabel(app.Microcontroller_LED_Control_via_BluetoothUIFigure);
            app.LED_STATUSGaugeLabel.Text = 'LED_STATUS';
            app.LED_STATUSGauge = uigauge(app.Microcontroller_LED_Control_via_BluetoothUIFigure, 'linear');
            app.LED_STATUSGauge.Limits = [0 9];
            app.LED_STATUSGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Bluetooth_Controlled_LED

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Microcontroller_LED_Control_via_BluetoothUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Microcontroller_LED_Control_via_BluetoothUIFigure)
        end
    end
end